classdef calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PoissonCalculatorUIFigure     matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        XYTab                         matlab.ui.container.Tab
        YButton_2                     matlab.ui.control.Button
        XButton_2                     matlab.ui.control.Button
        YButton                       matlab.ui.control.Button
        XButton                       matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        EButton                       matlab.ui.control.Button
        EButton_3                     matlab.ui.control.Button
        YButton_4                     matlab.ui.control.Button
        XButton_4                     matlab.ui.control.Button
        YButton_3                     matlab.ui.control.Button
        XButton_3                     matlab.ui.control.Button
        Button_32                     matlab.ui.control.Button
        Button_33                     matlab.ui.control.Button
        Button_34                     matlab.ui.control.Button
        Button_35                     matlab.ui.control.Button
        WZTab                         matlab.ui.container.Tab
        ZButton_2                     matlab.ui.control.Button
        WButton_2                     matlab.ui.control.Button
        ZButton                       matlab.ui.control.Button
        WButton                       matlab.ui.control.Button
        Button_4                      matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        EButton_2                     matlab.ui.control.Button
        EButton_4                     matlab.ui.control.Button
        ZButton_3                     matlab.ui.control.Button
        WButton_3                     matlab.ui.control.Button
        ZButton_4                     matlab.ui.control.Button
        WButton_4                     matlab.ui.control.Button
        Button_36                     matlab.ui.control.Button
        Button_37                     matlab.ui.control.Button
        Button_38                     matlab.ui.control.Button
        Button_39                     matlab.ui.control.Button
        BinaryoperationsPanel         matlab.ui.container.Panel
        traceABButton                 matlab.ui.control.Button
        ABButton                      matlab.ui.control.Button
        ABButton_2                    matlab.ui.control.Button
        ABButton_3                    matlab.ui.control.Button
        ABButton_4                    matlab.ui.control.Button
        ColumnPanel                   matlab.ui.container.Panel
        Button_5                      matlab.ui.control.Button
        Button_6                      matlab.ui.control.Button
        Button_7                      matlab.ui.control.Button
        Button_8                      matlab.ui.control.Button
        Button_9                      matlab.ui.control.Button
        Button_10                     matlab.ui.control.Button
        RButton                       matlab.ui.control.Button
        RButton_2                     matlab.ui.control.Button
        RButton_5                     matlab.ui.control.Button
        TabGroup2                     matlab.ui.container.TabGroup
        RowinXYTab                    matlab.ui.container.Tab
        Button_11                     matlab.ui.control.Button
        Button_12                     matlab.ui.control.Button
        Button_13                     matlab.ui.control.Button
        Button_14                     matlab.ui.control.Button
        Button_15                     matlab.ui.control.Button
        Button_16                     matlab.ui.control.Button
        RButton_3                     matlab.ui.control.Button
        RButton_4                     matlab.ui.control.Button
        RButton_6                     matlab.ui.control.Button
        RowinWZTab                    matlab.ui.container.Tab
        Button_17                     matlab.ui.control.Button
        Button_18                     matlab.ui.control.Button
        Button_19                     matlab.ui.control.Button
        Button_20                     matlab.ui.control.Button
        Button_21                     matlab.ui.control.Button
        Button_22                     matlab.ui.control.Button
        RButton_7                     matlab.ui.control.Button
        RButton_8                     matlab.ui.control.Button
        RButton_9                     matlab.ui.control.Button
        ProjectionsPanel              matlab.ui.container.Panel
        Button_23                     matlab.ui.control.Button
        Button_24                     matlab.ui.control.Button
        Button_25                     matlab.ui.control.Button
        Button_26                     matlab.ui.control.Button
        SubstitutionXYWZPanel         matlab.ui.container.Panel
        XYWZButton                    matlab.ui.control.Button
        WZXYButton                    matlab.ui.control.Button
        MemorystackPanel              matlab.ui.container.Panel
        StackATextAreaLabel           matlab.ui.control.Label
        StackATextArea                matlab.ui.control.TextArea
        StackBTextAreaLabel           matlab.ui.control.Label
        StackBTextArea                matlab.ui.control.TextArea
        Button_27                     matlab.ui.control.Button
        Button_28                     matlab.ui.control.Button
        EditField                     matlab.ui.control.NumericEditField
        Button_29                     matlab.ui.control.Button
        Button_30                     matlab.ui.control.Button
        EditField_2                   matlab.ui.control.NumericEditField
        MoveupButton                  matlab.ui.control.Button
        Button_31                     matlab.ui.control.Button
        RecordaresultinButtonGroup    matlab.ui.container.ButtonGroup
        NextemptycellButton           matlab.ui.control.RadioButton
        CurrentcellButton             matlab.ui.control.RadioButton
        ResultsarerecordedLabel       matlab.ui.control.Label
        instackBLabel                 matlab.ui.control.Label
        LoadingpanelPanel             matlab.ui.container.Panel
        OptionsforXYvarietyghfpcxyLabel  matlab.ui.control.Label
        OptionsforWZvarietyghfpcwzuu0Label  matlab.ui.control.Label
        FunctionEditFieldLabel        matlab.ui.control.Label
        FunctionEditField             matlab.ui.control.EditField
        VarietyDropDownLabel          matlab.ui.control.Label
        VarietyDropDown               matlab.ui.control.DropDown
        LoadButton                    matlab.ui.control.Button
        stackALabel                   matlab.ui.control.Label
        CLEARALLButton                matlab.ui.control.Button
        LogofoperationsTextAreaLabel  matlab.ui.control.Label
        LogofoperationsTextArea       matlab.ui.control.TextArea
        NullaryoperationsotherPanel   matlab.ui.container.Panel
        IdentmatrixButton             matlab.ui.control.Button
        traceButton                   matlab.ui.control.Button
        ZeromatrixButton              matlab.ui.control.Button
        FormButton                    matlab.ui.control.Button
        logButton                     matlab.ui.control.Button
        ScalebyEditFieldLabel         matlab.ui.control.Label
        ScalebyEditField              matlab.ui.control.NumericEditField
        ScaleButton                   matlab.ui.control.Button
        UnaryoperationstoButtonGroup  matlab.ui.container.ButtonGroup
        StackAButton                  matlab.ui.control.RadioButton
        StackBButton                  matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        mainapp % the calling app
        memory % the stacks of cells; property of type memoryStack
    end
    
    methods (Access = private)
        
        function update_output(app,output)
            if isempty(output)
                return;
            end
            c = length(app.LogofoperationsTextArea.Value) + length(app.LogofoperationsTextArea.Value{1});
            if c > 1
                app.LogofoperationsTextArea.Value = [output; app.LogofoperationsTextArea.Value];
            else
                app.LogofoperationsTextArea.Value = output;
            end  
        end
        
        function update_stack(app,type,index)
            f = app.memory.get(type,index);
            output = app.memory.data.disp2cell(f);
            if strcmpi(type,'A')
                app.EditField.Value = index;
                app.StackATextArea.Value = output;
                app.EditFieldValueChanged();
            end
            if strcmpi(type,'B')
                app.EditField_2.Value = index;
                app.StackBTextArea.Value = output;
                app.EditField_2ValueChanged();
            end
        end
        
        function leftRightArrows(app,type,inc)
            %type = 'A' or 'B', inc = +1 or -1; updates the textfield
            app.memory.set_current(type,app.memory.get_current(type) + inc);
            val = app.memory.get_current(type);
            app.update_stack(type,val);
            if strcmpi(type,'A')
                app.EditField.Value = val;
            elseif strcmpi(type,'B')
                app.EditField_2.Value = val;
            end
        end
        
        function applicable = unaryOperationCheck(app,icell,variety,type)
            output = cell(0);
            if isempty(icell)
                applicable = false;
                app.update_output({'Empty cell!'});
                return;
            end
            %checks the conditions for applying an operation
            %type can be 'matrix' or 'polynomial'; 
            %variety can be (W,Z) or (X,Y)
            %if conditions are satisfied, returns true
            %if not, returns false and makes a record in the log
            if (contains(icell.variety,'X')&&...
                    contains(variety,'W')) ||...
                    (contains(icell.variety,'W') && ...
                    contains(variety,'X'))
                output = {'Variety mismatch!'};
            elseif ~strncmpi(type, icell.type,1)
                if strncmpi(type,'m',1)
                    output = {'Attempted to apply a scalar operation to a matrix!'};
                elseif strncmpi(type,'p',1)
                    output = {'Attempted to apply a matrix operation to a scalar!'};
                end
            end
            if ~isempty(output)
                applicable = false;
                app.update_output(output);
            else
               applicable = true; 
            end
        end
        
        function unaryOperation(app,operation,variety,type,varargin)
            c = 0; %just for the case we perform scaling operation
            if ~isempty(varargin)
                for i = 1:length(varargin)
                    if ischar(varargin{i})
                        operation_name = varargin{i}; %for making log of operations
                    elseif isnumeric(varargin{i})
                        %only for the case of rescaling a function/matrix
                        c = varargin{i};
                    end
                end
            end
            uscheme = app.memory.unary_scheme;
            current = app.memory.get_current(uscheme);
            icell = app.memory.get(uscheme, current,'cell');
            if app.unaryOperationCheck(icell,variety,type)
                f = app.memory.applyUnaryOperation(icell.f,operation,c);
                app.memory.record(f);
                app.update_stack(app.memory.record_stack,...
                    app.memory.get_current(app.memory.record_stack));
                if ~isempty(varargin) 
                    %in case the text of the button has been sent, update
                    %the log; I use this approach for the button names are 
                    %in unicode
                    log = cell(1);
                    log{1} = append(operation_name, ' to ', uscheme, ...
                        num2str(current), '; saved in ', ...
                        app.memory.record_stack, ... 
                        num2str(app.memory.get_current(app.memory.record_stack)));
                    app.update_output(log)
                end
            end 
        end
        
        function applicable = binaryOperationCheck(app, cell1,cell2)
            %we need them just to have the same types and varieties
            if isempty(cell1)||isempty(cell2)
                app.update_output({'Empty cells!'});
                return;
            end
            check = strcmp(cell1.type,cell2.type);
            if ~check
                app.update_output({'Attempted to apply a binary operation to a polynomial and a matrix.'});
                applicable = false;
                return;
            end
            check = strcmp(cell1.variety,cell2.variety)...
                || (~contains(cell1.variety,("X"|"Y"|"W"|"Z"))) ...
                || (~contains(cell2.variety,("X"|"Y"|"W"|"Z")));
            if ~check
                app.update_output({'Variety mismatch!'});
                applicable = false;
            else
                applicable = true;
            end
        end
    
        
        function binaryOperation(app,operation,varargin)
            if ~isempty(varargin)
                operation_name = varargin{1};
            end
            currA = app.memory.get_current('A');
            currB = app.memory.get_current('B');
            cell1 = app.memory.get('A',currA,'cell');
            cell2 = app.memory.get('B',currB,'cell');
            if app.binaryOperationCheck(cell1,cell2)
                f = app.memory.applyBinaryOperation(cell1.f,cell2.f,operation);
                app.memory.record(f);
                app.update_stack(app.memory.record_stack,...
                    app.memory.get_current(app.memory.record_stack));
                if ~isempty(varargin) 
                    log = cell(1);
                    if ~strcmpi(operation_name,'trace')
                        log{1} = append('A',num2str(currA),operation_name,'B',num2str(currB),...
                            '; saved in',app.memory.record_stack,...
                            num2str(app.memory.get_current(app.memory.record_stack)));
                    elseif strcmpi(operation_name,'trace')
                        log{1} = append('trace(A',num2str(currA), '*B', num2str(currB),...
                            '); saved in',app.memory.record_stack,...
                            num2str(app.memory.get_current(app.memory.record_stack)));
                    end
                    app.update_output(log)
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp = mainapp;
            app.memory = memoryStack('data',mainapp.data);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.unaryOperation('gradX','XY','polynomial',app.Button.Text);
        end

        % Close request function: PoissonCalculatorUIFigure
        function PoissonCalculatorUIFigureCloseRequest(app, event)
            app.mainapp.PoissonCalculatorButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            type = app.VarietyDropDown.Value;
            if contains(type,("X"|"Y"))
                type = 'xy';
            elseif contains(type,("W"|"Z"))
                type = 'wz';
            end
            output = app.memory.load_str(app.FunctionEditField.Value,type);
            load2stack = app.memory.load2stack;
            if ~isempty(output)
                app.update_output(output);
            else
               app.update_stack(load2stack,app.memory.current_A);
               output = {append('Loaded ', app.FunctionEditField.Value,...
                  ' to ',load2stack, num2str(app.memory.current_A))};
               app.update_output(output);
            end
        end

        % Button pushed function: Button_28
        function Button_28Pushed(app, event)
            app.leftRightArrows('A',1);
            app.EditFieldValueChanged;
        end

        % Button pushed function: Button_27
        function Button_27Pushed(app, event)
            app.leftRightArrows('A',-1); 
            app.EditFieldValueChanged; 
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            if value > 2 && value < app.memory.MAX_SIZE-1
                app.Button_27.Enable = true;
                app.Button_28.Enable = true;
                return; %I just don't want to compute stackLength each time
            end
            if value == 1
                app.Button_27.Enable = false;
            elseif value == app.memory.stackLength('A')
                app.Button_28.Enable = false;
            else
                app.Button_27.Enable = true;
                app.Button_28.Enable = true;
            end
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            if value > 2 && value < app.memory.MAX_SIZE-1
                app.Button_29.Enable = true;
                app.Button_30.Enable = true;
                return; %I just don't want to compute stackLength each time
            end
            if value == 1
                app.Button_29.Enable = false;
            elseif value == app.memory.stackLength('B')
                app.Button_30.Enable = false;
            else
                app.Button_29.Enable = true;
                app.Button_30.Enable = true;
            end            
        end

        % Button pushed function: Button_29
        function Button_29Pushed(app, event)
            app.leftRightArrows('B',-1); 
            app.EditField_2ValueChanged;             
        end

        % Button pushed function: Button_30
        function Button_30Pushed(app, event)
            app.leftRightArrows('B',1); 
            app.EditField_2ValueChanged;
        end

        % Button pushed function: MoveupButton
        function MoveupButtonPushed(app, event)
            app.memory.stack_A{app.memory.current_A} =...
                app.memory.stack_B{app.memory.current_B};
            app.memory.stack_B{app.memory.current_B} = cell(0);
            app.update_stack('B',app.memory.current_B);
            app.update_stack('A',app.memory.current_A);
        end

        % Button pushed function: Button_31
        function Button_31Pushed(app, event)
            app.memory.stack_B{app.memory.current_B} =...
                app.memory.stack_A{app.memory.current_A};
            app.memory.stack_A{app.memory.current_A} = cell(0);
            app.update_stack('B',app.memory.current_B);
            app.update_stack('A',app.memory.current_A);
        end

        % Selection changed function: RecordaresultinButtonGroup
        function RecordaresultinButtonGroupSelectionChanged(app, event)

            if app.NextemptycellButton.Value
                app.memory.record_scheme = 'next';
            elseif app.CurrentcellButton.Value
                app.memory.record_scheme = 'current';
            end
        end

        % Selection changed function: UnaryoperationstoButtonGroup
        function UnaryoperationstoButtonGroupSelectionChanged(app, event)
            if app.StackAButton.Value
                app.memory.unary_scheme = 'A';
            elseif app.StackBButton.Value
                app.memory.unary_scheme = 'B';
            end
        end

        % Button pushed function: XYWZButton
        function XYWZButtonPushed(app, event)
            if isempty(app.memory.data.birat)
                app.update_output({'(W,Z)-variety has not been constructed!'});
                return;
            end
            app.unaryOperation('XY2WZ','XY','any',app.XYWZButton.Text);
        end

        % Button pushed function: WZXYButton
        function WZXYButtonPushed(app, event)
            if isempty(app.memory.data.birat)
                app.update_output({'(W,Z)-variety has not been constructed!'});
                return;
            end
            if isempty(app.memory.data.birat.U_1)
                app.memory.data.birat.invU();
            end
            app.unaryOperation('WZ2XY','WZ','any',app.WZXYButton.Text);
        end

        % Button pushed function: Button_23
        function Button_23Pushed(app, event)
            app.unaryOperation('proj_u','any','matrix', app.Button_23.Text);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.unaryOperation('gradY','XY','polynomial',app.Button_2.Text);
        end

        % Button pushed function: Button_24
        function Button_24Pushed(app, event)
            app.unaryOperation('proj_l','any','matrix', app.Button_24.Text);
        end

        % Button pushed function: Button_25
        function Button_25Pushed(app, event)
            app.unaryOperation('proj_d','any','matrix', app.Button_25.Text);
        end

        % Button pushed function: Button_26
        function Button_26Pushed(app, event)
            app.unaryOperation('proj_h','any','matrix', app.Button_26.Text);
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            app.unaryOperation('dX','XY','matrix', app.XButton.Text);
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            app.unaryOperation('Xd','XY','matrix',app.XButton_2.Text);
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            app.unaryOperation('dY','XY','matrix',app.YButton.Text);
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            app.unaryOperation('Yd','XY','matrix',app.YButton_2.Text);
        end

        % Button pushed function: EButton
        function EButtonPushed(app, event)
            app.unaryOperation('ERXY','XY','polynomial',app.EButton.Text);
        end

        % Button pushed function: EButton_3
        function EButton_3Pushed(app, event)
            app.unaryOperation('ELXY','XY','polynomial',app.EButton_3.Text);
        end

        % Button pushed function: EButton_2
        function EButton_2Pushed(app, event)
            app.unaryOperation('ERWZ','WZ','polynomial',app.EButton_2.Text);
        end

        % Button pushed function: EButton_4
        function EButton_4Pushed(app, event)
            app.unaryOperation('ELWZ','WZ','polynomial',app.EButton_4.Text);
        end

        % Button pushed function: XButton_3
        function XButton_3Pushed(app, event)
            app.unaryOperation('gradXX','XY','polynomial',app.XButton_3.Text);
        end

        % Button pushed function: XButton_4
        function XButton_4Pushed(app, event)
            app.unaryOperation('XgradX','XY','polynomial',app.XButton_4.Text);
        end

        % Button pushed function: YButton_3
        function YButton_3Pushed(app, event)
            app.unaryOperation('gradYY','XY','polynomial',app.YButton_3.Text);
        end

        % Button pushed function: YButton_4
        function YButton_4Pushed(app, event)
            app.unaryOperation('YgradY','XY','polynomial',app.YButton_4.Text);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.unaryOperation('gradW','WZ','polynomial',app.Button_3.Text);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.unaryOperation('gradZ','WZ','polynomial',app.Button_4.Text);
        end

        % Button pushed function: WButton
        function WButtonPushed(app, event)
            app.unaryOperation('dW','WZ','matrix',app.WButton.Text);
        end

        % Button pushed function: WButton_2
        function WButton_2Pushed(app, event)
            app.unaryOperation('Wd','WZ','matrix',app.WButton_2.Text);
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            app.unaryOperation('dZ','WZ','matrix',app.ZButton.Text);
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            app.unaryOperation('Zd','WZ','matrix',app.ZButton_2.Text);
        end

        % Button pushed function: WButton_4
        function WButton_4Pushed(app, event)
            app.unaryOperation('gradWW','WZ','polynomial',app.WButton_4.Text);
        end

        % Button pushed function: ZButton_4
        function ZButton_4Pushed(app, event)
            app.unaryOperation('gradZZ','WZ','polynomial', app.ZButton_4.Text);
        end

        % Button pushed function: WButton_3
        function WButton_3Pushed(app, event)
            app.unaryOperation('WgradW','WZ','polynomial', app.WButton_3.Text);
        end

        % Button pushed function: ZButton_3
        function ZButton_3Pushed(app, event)
            app.unaryOperation('ZgradZ','WZ','polynomial', app.ZButton_3.Text);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            app.unaryOperation('grxy','XY','matrix', app.Button_11.Text);
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            app.unaryOperation('ggrxy','XY','matrix', app.Button_13.Text);
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.unaryOperation('gggrxy','XY','matrix', app.Button_14.Text);
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            app.unaryOperation('gsrxy','XY','matrix', app.Button_12.Text);
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            app.unaryOperation('ggsrxy','XY','matrix', app.Button_15.Text);
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            app.unaryOperation('gggsrxy','XY','matrix', app.Button_16.Text);
        end

        % Button pushed function: RButton_3
        function RButton_3Pushed(app, event)
            app.unaryOperation('rRplusXY','XY','matrix', app.RButton_3.Text);
        end

        % Button pushed function: RButton_4
        function RButton_4Pushed(app, event)
            app.unaryOperation('rRminusXY','XY','matrix', app.RButton_4.Text);
        end

        % Button pushed function: RButton_6
        function RButton_6Pushed(app, event)
            app.unaryOperation('rR0XY','XY','matrix', app.RButton_6.Text);
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            app.unaryOperation('grwz','WZ','matrix', app.Button_17.Text);
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            app.unaryOperation('ggrwz','WZ','matrix', app.Button_19.Text);
        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
            app.unaryOperation('gggrwz','WZ','matrix', app.Button_20.Text);
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            app.unaryOperation('gsrwz','WZ','matrix', app.Button_18.Text);
        end

        % Button pushed function: Button_21
        function Button_21Pushed(app, event)
            app.unaryOperation('ggsrwz','WZ','matrix', app.Button_21.Text);
        end

        % Button pushed function: Button_22
        function Button_22Pushed(app, event)
            app.unaryOperation('gggsrwz','WZ','matrix', app.Button_22.Text);
        end

        % Button pushed function: RButton_7
        function RButton_7Pushed(app, event)
            app.unaryOperation('rRplusWZ','WZ','matrix', app.RButton_7.Text);
        end

        % Button pushed function: RButton_8
        function RButton_8Pushed(app, event)
            app.unaryOperation('rRminusWZ','WZ','matrix', app.RButton_8.Text);
        end

        % Button pushed function: RButton_9
        function RButton_9Pushed(app, event)
            app.unaryOperation('rR0WZ','WZ','matrix', app.RButton_9.Text);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.unaryOperation('gc','any','matrix', app.Button_5.Text);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.unaryOperation('ggc','any','matrix', app.Button_7.Text);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.unaryOperation('gggc','any','matrix', app.Button_8.Text);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.unaryOperation('gsc','any','matrix', app.Button_6.Text);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.unaryOperation('ggsc','any','matrix', app.Button_9.Text);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            app.unaryOperation('gggsc','any','matrix', app.Button_10.Text);
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            app.unaryOperation('cRplus','any','matrix',app.RButton.Text);
        end

        % Button pushed function: RButton_2
        function RButton_2Pushed(app, event)
            app.unaryOperation('cRminus','any','matrix',app.RButton_2.Text);
        end

        % Button pushed function: RButton_5
        function RButton_5Pushed(app, event)
            app.unaryOperation('cR0','any','matrix',app.RButton_5.Text);
        end

        % Button pushed function: traceButton
        function traceButtonPushed(app, event)
            app.unaryOperation('trace','any','matrix',app.traceButton.Text);
        end

        % Button pushed function: IdentmatrixButton
        function IdentmatrixButtonPushed(app, event)

            f = app.memory.applyUnaryOperation(1,'identity');
            app.memory.record(f);
            which_stack = app.memory.record_stack;
            where_to = app.memory.get_current(app.memory.record_stack);
            app.update_stack(which_stack,where_to);
                
            log = cell(1);
            log{1} = append('Id matrix saved in ', ...
                which_stack, ... 
                num2str(where_to));
            app.update_output(log);
        end

        % Button pushed function: ZeromatrixButton
        function ZeromatrixButtonPushed(app, event)
            f = app.memory.applyUnaryOperation(1,'zero');
            app.memory.record(f);
            which_stack = app.memory.record_stack;
            where_to = app.memory.get_current(app.memory.record_stack);
            app.update_stack(which_stack,where_to);
                
            log = cell(1);
            log{1} = append('0-matrix saved in ', ...
                which_stack, ... 
                num2str(where_to));
            app.update_output(log);
        end

        % Button pushed function: traceABButton
        function traceABButtonPushed(app, event)
            app.binaryOperation('trace','trace');
        end

        % Button pushed function: ABButton
        function ABButtonPushed(app, event)
            app.binaryOperation('*','*');
        end

        % Button pushed function: ABButton_2
        function ABButton_2Pushed(app, event)
            app.binaryOperation('+','+');
        end

        % Button pushed function: ABButton_3
        function ABButton_3Pushed(app, event)
            app.binaryOperation('-','-');
        end

        % Button pushed function: FormButton
        function FormButtonPushed(app, event)
            app.unaryOperation('form','any','none');
            log = cell(1);
            log{1} = append('The form saved in ', ...
                app.memory.record_stack, ... 
                num2str(app.memory.get_current(app.memory.record_stack)));
            app.update_output(log);            
        end

        % Button pushed function: CLEARALLButton
        function CLEARALLButtonPushed(app, event)
            app.EditField.Value = 1;
            app.EditField_2.Value = 1;
            app.StackATextArea.Value = {char.empty(0)};
            app.StackBTextArea.Value = {char.empty(0)};
            app.EditFieldValueChanged;
            app.EditField_2ValueChanged;
            
            app.memory = memoryStack('data',app.memory.data);
            %to make sure we record in the right stack:
            app.UnaryoperationstoButtonGroupSelectionChanged();
        end

        % Button pushed function: ABButton_4
        function ABButton_4Pushed(app, event)
            app.binaryOperation('.','.');
        end

        % Button pushed function: logButton
        function logButtonPushed(app, event)
            app.unaryOperation('log','any','polynomial', app.logButton.Text);
        end

        % Button pushed function: Button_32
        function Button_32Pushed(app, event)
            app.unaryOperation('XIRXY','XY','polynomial',app.Button_32.Text);
        end

        % Button pushed function: Button_33
        function Button_33Pushed(app, event)
            app.unaryOperation('XILXY','XY','polynomial',app.Button_33.Text);
        end

        % Button pushed function: Button_34
        function Button_34Pushed(app, event)
            app.unaryOperation('ETARXY','XY','polynomial',app.Button_34.Text);
        end

        % Button pushed function: Button_35
        function Button_35Pushed(app, event)
            app.unaryOperation('ETALXY','XY','polynomial',app.Button_35.Text);
        end

        % Button pushed function: Button_36
        function Button_36Pushed(app, event)
            app.unaryOperation('XIRWZ','WZ','polynomial',app.Button_36.Text);
        end

        % Button pushed function: Button_37
        function Button_37Pushed(app, event)
            app.unaryOperation('XILWZ','WZ','polynomial',app.Button_37.Text);
        end

        % Button pushed function: Button_38
        function Button_38Pushed(app, event)
            app.unaryOperation('ETARWZ','WZ','polynomial',app.Button_38.Text);
        end

        % Button pushed function: Button_39
        function Button_39Pushed(app, event)
            app.unaryOperation('ETALWZ','WZ','polynomial',app.Button_39.Text);
        end

        % Button pushed function: ScaleButton
        function ScaleButtonPushed(app, event)
            c = app.ScalebyEditField.Value;
            app.unaryOperation('scale','any','none',append('Mult by ',num2str(c)),c);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PoissonCalculatorUIFigure = uifigure('Visible', 'off');
            app.PoissonCalculatorUIFigure.Name = 'Poisson Calculator';
            app.PoissonCalculatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @PoissonCalculatorUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.PoissonCalculatorUIFigure);
            app.XYTab = uitab(app.TabGroup);
            app.XYTab.Title = '∇(X,Y)';
            app.YButton_2 = uibutton(app.XYTab, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = 'Y·';
            app.XButton_2 = uibutton(app.XYTab, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = 'X·';
            app.YButton = uibutton(app.XYTab, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = '·Y';
            app.XButton = uibutton(app.XYTab, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = '·X';
            app.Button_2 = uibutton(app.XYTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '∇ʸ';
            app.Button = uibutton(app.XYTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '∇ˣ';
            app.EButton = uibutton(app.XYTab, 'push');
            app.EButton.ButtonPushedFcn = createCallbackFcn(app, @EButtonPushed, true);
            app.EButton.Text = 'Eᴿ';
            app.EButton_3 = uibutton(app.XYTab, 'push');
            app.EButton_3.ButtonPushedFcn = createCallbackFcn(app, @EButton_3Pushed, true);
            app.EButton_3.Text = 'Eᴸ';
            app.YButton_4 = uibutton(app.XYTab, 'push');
            app.YButton_4.ButtonPushedFcn = createCallbackFcn(app, @YButton_4Pushed, true);
            app.YButton_4.Text = 'Y·∇ʸ';
            app.XButton_4 = uibutton(app.XYTab, 'push');
            app.XButton_4.ButtonPushedFcn = createCallbackFcn(app, @XButton_4Pushed, true);
            app.XButton_4.Text = 'X·∇ˣ';
            app.YButton_3 = uibutton(app.XYTab, 'push');
            app.YButton_3.ButtonPushedFcn = createCallbackFcn(app, @YButton_3Pushed, true);
            app.YButton_3.Text = '∇ʸ·Y';
            app.XButton_3 = uibutton(app.XYTab, 'push');
            app.XButton_3.ButtonPushedFcn = createCallbackFcn(app, @XButton_3Pushed, true);
            app.XButton_3.Text = '∇ˣ·X';
            app.Button_32 = uibutton(app.XYTab, 'push');
            app.Button_32.ButtonPushedFcn = createCallbackFcn(app, @Button_32Pushed, true);
            app.Button_32.Text = 'ξᴿ';
            app.Button_33 = uibutton(app.XYTab, 'push');
            app.Button_33.ButtonPushedFcn = createCallbackFcn(app, @Button_33Pushed, true);
            app.Button_33.Text = 'ξᴸ';
            app.Button_34 = uibutton(app.XYTab, 'push');
            app.Button_34.ButtonPushedFcn = createCallbackFcn(app, @Button_34Pushed, true);
            app.Button_34.Text = 'ηᴿ';
            app.Button_35 = uibutton(app.XYTab, 'push');
            app.Button_35.ButtonPushedFcn = createCallbackFcn(app, @Button_35Pushed, true);
            app.Button_35.Text = 'ηᴸ';
            app.WZTab = uitab(app.TabGroup);
            app.WZTab.Title = '∇(W,Z)';
            app.ZButton_2 = uibutton(app.WZTab, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = 'Z·';
            app.WButton_2 = uibutton(app.WZTab, 'push');
            app.WButton_2.ButtonPushedFcn = createCallbackFcn(app, @WButton_2Pushed, true);
            app.WButton_2.Text = 'W·';
            app.ZButton = uibutton(app.WZTab, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = '·Z';
            app.WButton = uibutton(app.WZTab, 'push');
            app.WButton.ButtonPushedFcn = createCallbackFcn(app, @WButtonPushed, true);
            app.WButton.Text = '·W';
            app.Button_4 = uibutton(app.WZTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '∇ᶻ';
            app.Button_3 = uibutton(app.WZTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '∇ʷ';
            app.EButton_2 = uibutton(app.WZTab, 'push');
            app.EButton_2.ButtonPushedFcn = createCallbackFcn(app, @EButton_2Pushed, true);
            app.EButton_2.Text = 'Eᴿ';
            app.EButton_4 = uibutton(app.WZTab, 'push');
            app.EButton_4.ButtonPushedFcn = createCallbackFcn(app, @EButton_4Pushed, true);
            app.EButton_4.Text = 'Eᴸ';
            app.ZButton_3 = uibutton(app.WZTab, 'push');
            app.ZButton_3.ButtonPushedFcn = createCallbackFcn(app, @ZButton_3Pushed, true);
            app.ZButton_3.Text = 'Z·∇ᶻ';
            app.WButton_3 = uibutton(app.WZTab, 'push');
            app.WButton_3.ButtonPushedFcn = createCallbackFcn(app, @WButton_3Pushed, true);
            app.WButton_3.Text = 'W·∇ʷ';
            app.ZButton_4 = uibutton(app.WZTab, 'push');
            app.ZButton_4.ButtonPushedFcn = createCallbackFcn(app, @ZButton_4Pushed, true);
            app.ZButton_4.Text = '∇ᶻ·Z';
            app.WButton_4 = uibutton(app.WZTab, 'push');
            app.WButton_4.ButtonPushedFcn = createCallbackFcn(app, @WButton_4Pushed, true);
            app.WButton_4.Text = '∇ʷ·W';
            app.Button_36 = uibutton(app.WZTab, 'push');
            app.Button_36.ButtonPushedFcn = createCallbackFcn(app, @Button_36Pushed, true);
            app.Button_36.Text = 'ξᴿ';
            app.Button_37 = uibutton(app.WZTab, 'push');
            app.Button_37.ButtonPushedFcn = createCallbackFcn(app, @Button_37Pushed, true);
            app.Button_37.Text = 'ξᴸ';
            app.Button_38 = uibutton(app.WZTab, 'push');
            app.Button_38.ButtonPushedFcn = createCallbackFcn(app, @Button_38Pushed, true);
            app.Button_38.Text = 'ηᴿ';
            app.Button_39 = uibutton(app.WZTab, 'push');
            app.Button_39.ButtonPushedFcn = createCallbackFcn(app, @Button_39Pushed, true);
            app.Button_39.Text = 'ηᴸ';
            app.BinaryoperationsPanel = uipanel(app.PoissonCalculatorUIFigure);
            app.BinaryoperationsPanel.Title = 'Binary operations';
            app.traceABButton = uibutton(app.BinaryoperationsPanel, 'push');
            app.traceABButton.ButtonPushedFcn = createCallbackFcn(app, @traceABButtonPushed, true);
            app.traceABButton.Text = 'trace(A•B)';
            app.ABButton = uibutton(app.BinaryoperationsPanel, 'push');
            app.ABButton.ButtonPushedFcn = createCallbackFcn(app, @ABButtonPushed, true);
            app.ABButton.Text = 'A•B';
            app.ABButton_2 = uibutton(app.BinaryoperationsPanel, 'push');
            app.ABButton_2.ButtonPushedFcn = createCallbackFcn(app, @ABButton_2Pushed, true);
            app.ABButton_2.Text = 'A+B';
            app.ABButton_3 = uibutton(app.BinaryoperationsPanel, 'push');
            app.ABButton_3.ButtonPushedFcn = createCallbackFcn(app, @ABButton_3Pushed, true);
            app.ABButton_3.Text = 'A-B';
            app.ABButton_4 = uibutton(app.BinaryoperationsPanel, 'push');
            app.ABButton_4.ButtonPushedFcn = createCallbackFcn(app, @ABButton_4Pushed, true);
            app.ABButton_4.Tooltip = {'Entrywise multiplication'};
            app.ABButton_4.Text = 'A.B';
            app.ColumnPanel = uipanel(app.PoissonCalculatorUIFigure);
            app.ColumnPanel.Title = 'Column';
            app.Button_5 = uibutton(app.ColumnPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = 'γᶜ';
            app.Button_6 = uibutton(app.ColumnPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '(γᶜ)*';
            app.Button_7 = uibutton(app.ColumnPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '1/(1-γᶜ)';
            app.Button_8 = uibutton(app.ColumnPanel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = 'γᶜ/(1-γᶜ)';
            app.Button_9 = uibutton(app.ColumnPanel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '1/(1-(γᶜ)*)';
            app.Button_10 = uibutton(app.ColumnPanel, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '(γᶜ)*/(1-(γᶜ)*)';
            app.RButton = uibutton(app.ColumnPanel, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = 'ᶜR₊';
            app.RButton_2 = uibutton(app.ColumnPanel, 'push');
            app.RButton_2.ButtonPushedFcn = createCallbackFcn(app, @RButton_2Pushed, true);
            app.RButton_2.Text = 'ᶜR₋';
            app.RButton_5 = uibutton(app.ColumnPanel, 'push');
            app.RButton_5.ButtonPushedFcn = createCallbackFcn(app, @RButton_5Pushed, true);
            app.RButton_5.Text = 'R₀';
            app.TabGroup2 = uitabgroup(app.PoissonCalculatorUIFigure);
            app.RowinXYTab = uitab(app.TabGroup2);
            app.RowinXYTab.Title = 'Row in (X,Y)';
            app.Button_11 = uibutton(app.RowinXYTab, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = 'γʳ';
            app.Button_12 = uibutton(app.RowinXYTab, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '(γʳ)*';
            app.Button_13 = uibutton(app.RowinXYTab, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '1/(1-γʳ)';
            app.Button_14 = uibutton(app.RowinXYTab, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = 'γʳ/(1-γʳ)';
            app.Button_15 = uibutton(app.RowinXYTab, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '1/(1-(γʳ)*)';
            app.Button_16 = uibutton(app.RowinXYTab, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '(γʳ)*/(1-(γʳ)*)';
            app.RButton_3 = uibutton(app.RowinXYTab, 'push');
            app.RButton_3.ButtonPushedFcn = createCallbackFcn(app, @RButton_3Pushed, true);
            app.RButton_3.Text = 'ʳR₊';
            app.RButton_4 = uibutton(app.RowinXYTab, 'push');
            app.RButton_4.ButtonPushedFcn = createCallbackFcn(app, @RButton_4Pushed, true);
            app.RButton_4.Text = 'ʳR₋';
            app.RButton_6 = uibutton(app.RowinXYTab, 'push');
            app.RButton_6.ButtonPushedFcn = createCallbackFcn(app, @RButton_6Pushed, true);
            app.RButton_6.Text = 'R₀';
            app.RowinWZTab = uitab(app.TabGroup2);
            app.RowinWZTab.Title = 'Row in (W,Z)';
            app.Button_17 = uibutton(app.RowinWZTab, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = 'γʳ';
            app.Button_18 = uibutton(app.RowinWZTab, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '(γʳ)*';
            app.Button_19 = uibutton(app.RowinWZTab, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '1/(1-γʳ)';
            app.Button_20 = uibutton(app.RowinWZTab, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Text = 'γʳ/(1-γʳ)';
            app.Button_21 = uibutton(app.RowinWZTab, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Button_21Pushed, true);
            app.Button_21.Text = '1/(1-(γʳ)*)';
            app.Button_22 = uibutton(app.RowinWZTab, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Button_22Pushed, true);
            app.Button_22.Text = '(γʳ)*/(1-(γʳ)*)';
            app.RButton_7 = uibutton(app.RowinWZTab, 'push');
            app.RButton_7.ButtonPushedFcn = createCallbackFcn(app, @RButton_7Pushed, true);
            app.RButton_7.Text = 'ʳR₊';
            app.RButton_8 = uibutton(app.RowinWZTab, 'push');
            app.RButton_8.ButtonPushedFcn = createCallbackFcn(app, @RButton_8Pushed, true);
            app.RButton_8.Text = 'ʳR₋';
            app.RButton_9 = uibutton(app.RowinWZTab, 'push');
            app.RButton_9.ButtonPushedFcn = createCallbackFcn(app, @RButton_9Pushed, true);
            app.RButton_9.Text = 'R₀';
            app.ProjectionsPanel = uipanel(app.PoissonCalculatorUIFigure);
            app.ProjectionsPanel.Title = 'Projections';
            app.Button_23 = uibutton(app.ProjectionsPanel, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @Button_23Pushed, true);
            app.Button_23.Tooltip = {'Projection to strictly upper triangular matrices.'};
            app.Button_23.Text = 'π > 0';
            app.Button_24 = uibutton(app.ProjectionsPanel, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @Button_24Pushed, true);
            app.Button_24.Tooltip = {'Projection to strictly lower triangular matrices.'};
            app.Button_24.Text = 'π < 0';
            app.Button_25 = uibutton(app.ProjectionsPanel, 'push');
            app.Button_25.ButtonPushedFcn = createCallbackFcn(app, @Button_25Pushed, true);
            app.Button_25.Tooltip = {'Projection on the diagonal matrices.'};
            app.Button_25.Text = 'π₀';
            app.Button_26 = uibutton(app.ProjectionsPanel, 'push');
            app.Button_26.ButtonPushedFcn = createCallbackFcn(app, @Button_26Pushed, true);
            app.Button_26.Tooltip = {'Projection on the diagonal matrices with trace zero.'};
            app.Button_26.Text = 'πₕ';
            app.SubstitutionXYWZPanel = uipanel(app.PoissonCalculatorUIFigure);
            app.SubstitutionXYWZPanel.Title = 'Substitution (X,Y) ⇄ (W,Z)';
            app.XYWZButton = uibutton(app.SubstitutionXYWZPanel, 'push');
            app.XYWZButton.ButtonPushedFcn = createCallbackFcn(app, @XYWZButtonPushed, true);
            app.XYWZButton.Tooltip = {'In other words, applies the birational transformation.'};
            app.XYWZButton.Text = '(X,Y) ↦ (W,Z)';
            app.WZXYButton = uibutton(app.SubstitutionXYWZPanel, 'push');
            app.WZXYButton.ButtonPushedFcn = createCallbackFcn(app, @WZXYButtonPushed, true);
            app.WZXYButton.Text = '(W,Z) ↦ (X,Y)';
            app.MemorystackPanel = uipanel(app.PoissonCalculatorUIFigure);
            app.MemorystackPanel.Title = 'Memory stack';
            app.StackATextAreaLabel = uilabel(app.MemorystackPanel);
            app.StackATextAreaLabel.Text = 'Stack A';
            app.StackATextArea = uitextarea(app.MemorystackPanel);
            app.StackBTextAreaLabel = uilabel(app.MemorystackPanel);
            app.StackBTextAreaLabel.Text = 'Stack B';
            app.StackBTextArea = uitextarea(app.MemorystackPanel);
            app.Button_27 = uibutton(app.MemorystackPanel, 'push');
            app.Button_27.ButtonPushedFcn = createCallbackFcn(app, @Button_27Pushed, true);
            app.Button_27.Enable = 'off';
            app.Button_27.Text = '<';
            app.Button_28 = uibutton(app.MemorystackPanel, 'push');
            app.Button_28.ButtonPushedFcn = createCallbackFcn(app, @Button_28Pushed, true);
            app.Button_28.Text = '>';
            app.EditField = uieditfield(app.MemorystackPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Editable = 'off';
            app.EditField.Value = 1;
            app.Button_29 = uibutton(app.MemorystackPanel, 'push');
            app.Button_29.ButtonPushedFcn = createCallbackFcn(app, @Button_29Pushed, true);
            app.Button_29.Enable = 'off';
            app.Button_29.Text = '<';
            app.Button_30 = uibutton(app.MemorystackPanel, 'push');
            app.Button_30.ButtonPushedFcn = createCallbackFcn(app, @Button_30Pushed, true);
            app.Button_30.Text = '>';
            app.EditField_2 = uieditfield(app.MemorystackPanel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = 1;
            app.MoveupButton = uibutton(app.MemorystackPanel, 'push');
            app.MoveupButton.ButtonPushedFcn = createCallbackFcn(app, @MoveupButtonPushed, true);
            app.MoveupButton.Text = 'Move up ⇧';
            app.Button_31 = uibutton(app.MemorystackPanel, 'push');
            app.Button_31.ButtonPushedFcn = createCallbackFcn(app, @Button_31Pushed, true);
            app.Button_31.Text = '⇩';
            app.RecordaresultinButtonGroup = uibuttongroup(app.PoissonCalculatorUIFigure);
            app.RecordaresultinButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RecordaresultinButtonGroupSelectionChanged, true);
            app.RecordaresultinButtonGroup.Title = 'Record a result in';
            app.NextemptycellButton = uiradiobutton(app.RecordaresultinButtonGroup);
            app.NextemptycellButton.Text = 'Next empty cell';
            app.NextemptycellButton.Value = true;
            app.CurrentcellButton = uiradiobutton(app.RecordaresultinButtonGroup);
            app.CurrentcellButton.Text = 'Current cell';
            app.ResultsarerecordedLabel = uilabel(app.RecordaresultinButtonGroup);
            app.ResultsarerecordedLabel.Text = 'Results are recorded';
            app.instackBLabel = uilabel(app.RecordaresultinButtonGroup);
            app.instackBLabel.Text = ' in stack B';
            app.LoadingpanelPanel = uipanel(app.PoissonCalculatorUIFigure);
            app.LoadingpanelPanel.Title = 'Loading panel';
            app.OptionsforXYvarietyghfpcxyLabel = uilabel(app.LoadingpanelPanel);
            app.OptionsforXYvarietyghfpcxyLabel.Text = 'Options for (X,Y)-variety: g,h,f,p,c,x,y';
            app.OptionsforWZvarietyghfpcwzuu0Label = uilabel(app.LoadingpanelPanel);
            app.OptionsforWZvarietyghfpcwzuu0Label.Text = 'Options for (W,Z)-variety: g,h,f,p,c,w,z,u,u0';
            app.FunctionEditFieldLabel = uilabel(app.LoadingpanelPanel);
            app.FunctionEditFieldLabel.Text = 'Function:';
            app.FunctionEditField = uieditfield(app.LoadingpanelPanel, 'text');
            app.VarietyDropDownLabel = uilabel(app.LoadingpanelPanel);
            app.VarietyDropDownLabel.Text = 'Variety:';
            app.VarietyDropDown = uidropdown(app.LoadingpanelPanel);
            app.VarietyDropDown.Items = {'(X,Y)', '(W,Z)'};
            app.VarietyDropDown.Value = '(X,Y)';
            app.LoadButton = uibutton(app.LoadingpanelPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.stackALabel = uilabel(app.LoadingpanelPanel);
            app.stackALabel.Text = '(stack A)';
            app.CLEARALLButton = uibutton(app.LoadingpanelPanel, 'push');
            app.CLEARALLButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARALLButtonPushed, true);
            app.CLEARALLButton.Tooltip = {'Clears and resets the memory.'};
            app.CLEARALLButton.Text = 'CLEAR ALL';
            app.LogofoperationsTextAreaLabel = uilabel(app.PoissonCalculatorUIFigure);
            app.LogofoperationsTextAreaLabel.Text = 'Log of operations';
            app.LogofoperationsTextArea = uitextarea(app.PoissonCalculatorUIFigure);
            app.LogofoperationsTextArea.Editable = 'off';
            app.NullaryoperationsotherPanel = uipanel(app.PoissonCalculatorUIFigure);
            app.NullaryoperationsotherPanel.Title = 'Nullary operations & other';
            app.IdentmatrixButton = uibutton(app.NullaryoperationsotherPanel, 'push');
            app.IdentmatrixButton.ButtonPushedFcn = createCallbackFcn(app, @IdentmatrixButtonPushed, true);
            app.IdentmatrixButton.Text = 'Ident matrix';
            app.traceButton = uibutton(app.NullaryoperationsotherPanel, 'push');
            app.traceButton.ButtonPushedFcn = createCallbackFcn(app, @traceButtonPushed, true);
            app.traceButton.Text = 'trace';
            app.ZeromatrixButton = uibutton(app.NullaryoperationsotherPanel, 'push');
            app.ZeromatrixButton.ButtonPushedFcn = createCallbackFcn(app, @ZeromatrixButtonPushed, true);
            app.ZeromatrixButton.Text = 'Zero matrix';
            app.FormButton = uibutton(app.NullaryoperationsotherPanel, 'push');
            app.FormButton.ButtonPushedFcn = createCallbackFcn(app, @FormButtonPushed, true);
            app.FormButton.Tooltip = {'Sets all non-zero values of a matrix to 1, so that one can determine easily the subspace it belongs to.'};
            app.FormButton.Text = 'Form';
            app.logButton = uibutton(app.NullaryoperationsotherPanel, 'push');
            app.logButton.ButtonPushedFcn = createCallbackFcn(app, @logButtonPushed, true);
            app.logButton.Text = 'log';
            app.ScalebyEditFieldLabel = uilabel(app.NullaryoperationsotherPanel);
            app.ScalebyEditFieldLabel.Text = 'Scale by';
            app.ScalebyEditField = uieditfield(app.NullaryoperationsotherPanel, 'numeric');
            app.ScaleButton = uibutton(app.NullaryoperationsotherPanel, 'push');
            app.ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleButtonPushed, true);
            app.ScaleButton.Text = 'Scale';
            app.UnaryoperationstoButtonGroup = uibuttongroup(app.PoissonCalculatorUIFigure);
            app.UnaryoperationstoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UnaryoperationstoButtonGroupSelectionChanged, true);
            app.UnaryoperationstoButtonGroup.Tooltip = {'Choose the stack to which the unary operations are applied.'};
            app.UnaryoperationstoButtonGroup.Title = 'Unary operations to';
            app.StackAButton = uiradiobutton(app.UnaryoperationstoButtonGroup);
            app.StackAButton.Text = 'Stack A';
            app.StackAButton.Value = true;
            app.StackBButton = uiradiobutton(app.UnaryoperationstoButtonGroup);
            app.StackBButton.Text = 'Stack B';
            % Show the figure after all components are created
            app.PoissonCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calculator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PoissonCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PoissonCalculatorUIFigure)
        end
    end
end