classdef launcher < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClusterstructuresonDGLnandGLnUIFigure  matlab.ui.Figure
        SettingsPanel                   matlab.ui.container.Panel
        CreateButton                    matlab.ui.control.Button
        nEditFieldLabel                 matlab.ui.control.Label
        nEditField                      matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        RowBDdataLabel                  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        ColumnBDdataLabel               matlab.ui.control.Label
        EditField_3Label                matlab.ui.control.Label
        EditField_3                     matlab.ui.control.EditField
        EditField_4Label                matlab.ui.control.Label
        EditField_4                     matlab.ui.control.EditField
        ConstructfunctionsCheckBox      matlab.ui.control.CheckBox
        ConstructthebracketCheckBox     matlab.ui.control.CheckBox
        ConstructcfunctionsCheckBox     matlab.ui.control.CheckBox
        CreateonlythequiverCheckBox     matlab.ui.control.CheckBox
        ChoosethegroupButtonGroup       matlab.ui.container.ButtonGroup
        DGLnButton                      matlab.ui.control.RadioButton
        GLnButton                       matlab.ui.control.RadioButton
        ConstructthemapCheckBox         matlab.ui.control.CheckBox
        PoissonCalculatorButton         matlab.ui.control.Button
        OutputTextAreaLabel             matlab.ui.control.Label
        OutputTextArea                  matlab.ui.control.TextArea
        OutputpanelPanel                matlab.ui.container.Panel
        ShowthequiverButton             matlab.ui.control.Button
        ShowallLfunctionsButton         matlab.ui.control.Button
        ShowafunctionEditFieldLabel     matlab.ui.control.Label
        ShowafunctionEditField          matlab.ui.control.EditField
        ShowButton                      matlab.ui.control.Button
        CleartheoutputButton            matlab.ui.control.Button
        ShowUButton                     matlab.ui.control.Button
        AcceptedtypesforDGLnghfpcuu0aLabel  matlab.ui.control.Label
        ShowU0Button                    matlab.ui.control.Button
        ShowinvUButton                  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        MutationsinXYTab                matlab.ui.container.Tab
        MutateButton                    matlab.ui.control.Button
        MutationsequenceEditFieldLabel  matlab.ui.control.Label
        MutationsequenceEditField       matlab.ui.control.EditField
        BracketinXYTab                  matlab.ui.container.Tab
        fLabel                          matlab.ui.control.Label
        fEditField                      matlab.ui.control.EditField
        fLabel_2                        matlab.ui.control.Label
        fEditField_2                    matlab.ui.control.EditField
        ComputeffButton                 matlab.ui.control.Button
        ComputelogflogfButton           matlab.ui.control.Button
        ComputeffButton_3               matlab.ui.control.Button
        ComputelogflogfButton_3         matlab.ui.control.Button
        ComputeffButton_4               matlab.ui.control.Button
        ComputelogflogfButton_4         matlab.ui.control.Button
        AcceptedtypesforDGLnghfpcxyLabel  matlab.ui.control.Label
        BracketinWZTab                  matlab.ui.container.Tab
        fLabel_3                        matlab.ui.control.Label
        ComputeffButton_2               matlab.ui.control.Button
        fLabel_5                        matlab.ui.control.Label
        ComputelogflogfButton_2         matlab.ui.control.Button
        NOTEifUisconstructedonecancomputeUWLabel  matlab.ui.control.Label
        fEditField_4                    matlab.ui.control.EditField
        fEditField_3                    matlab.ui.control.EditField
        Forinstancetypeu21w11Label      matlab.ui.control.Label
        AcceptedtypesforDGLnghfpcuu0wzaLabel  matlab.ui.control.Label
        RmatrixTab                      matlab.ui.container.Tab
        ShowtherowRmatrixButton         matlab.ui.control.Button
        ShowthecolumnRmatrixButton      matlab.ui.control.Button
        OntheXYvarietyLabel             matlab.ui.control.Label
        OntheWZvarietyLabel             matlab.ui.control.Label
        ShowtherowRmatrixButton_2       matlab.ui.control.Button
        DifferenceofrowRmatricesButton  matlab.ui.control.Button
        ShowonlytheimagesubspaceCheckBox  matlab.ui.control.CheckBox
        ConventiononTab                 matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = public)
        data %a container for all the data on cluster structures
    end
    
    properties (Access = private)
        PoissonCalculator % Description
    end
    
    
    methods (Access = private)
        function update_output(app,output)
            if isempty(output)
                return;
            end
            c = length(app.OutputTextArea.Value) + length(app.OutputTextArea.Value{1});
            if c > 1
                app.OutputTextArea.Value = [output; app.OutputTextArea.Value];
            else
                app.OutputTextArea.Value = output;
            end  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ConstructfunctionsCheckBoxValueChanged(app, event)
            value = app.ConstructfunctionsCheckBox.Value;
        end

        % Value changed function: CreateonlythequiverCheckBox
        function CreateonlythequiverCheckBoxValueChanged(app, event)
            value = app.CreateonlythequiverCheckBox.Value;
            if value == true
                app.ConstructfunctionsCheckBox.Enable = false;
                app.ConstructthebracketCheckBox.Enable = false;
                app.ConstructcfunctionsCheckBox.Enable = false;
                app.ConstructthemapCheckBox.Enable = false;
            elseif value == false
                app.ConstructfunctionsCheckBox.Enable = true;
                app.ConstructthebracketCheckBox.Enable = true;
                app.ConstructcfunctionsCheckBox.Enable = true; 
                app.ConstructthemapCheckBox.Enable = true;
            end
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            output = error_handler.BD_check('G1_r',app.EditField.Value,'G2_r',...
                app.EditField_2.Value,'G1_c',app.EditField_3.Value,'G2_c',...
                app.EditField_4.Value,'n',app.nEditField.Value,'build_birat',...
                app.ConstructthemapCheckBox.Value);
            if isempty(output)
                app.data = data_holder('G1_r',app.EditField.Value,'G2_r',...
                    app.EditField_2.Value,'G1_c',app.EditField_3.Value,'G2_c',...
                    app.EditField_4.Value,'only_quiver',app.CreateonlythequiverCheckBox.Value,...
                    'build_phi',app.ConstructfunctionsCheckBox.Value,'build_c',...
                    app.ConstructcfunctionsCheckBox.Value,'bracket',...
                    app.ConstructthebracketCheckBox.Value,'n',app.nEditField.Value,...
                    'type', app.DGLnButton.Value,'build_birat',app.ConstructthemapCheckBox.Value);
                app.update_output({'The cluster structure has been created.'});
            else
               app.update_output(output); 
            end
        end

        % Button pushed function: ShowallLfunctionsButton
        function ShowallLfunctionsButtonPushed(app, event)
            if ~isempty(app.data)
                app.update_output(app.data.display_L);
            end
        end

        % Button pushed function: ShowthequiverButton
        function ShowthequiverButtonPushed(app, event)
            if ~isempty(app.data)
               app.data.clust_str.q.plot();
            end
        end

        % Button pushed function: MutateButton
        function MutateButtonPushed(app, event)
            if ~isempty(app.data)
               output = app.data.mutate_seq(app.MutationsequenceEditField.Value);
                app.update_output(output);
            end
        end

        % Button pushed function: CleartheoutputButton
        function CleartheoutputButtonPushed(app, event)
            app.OutputTextArea.Value = {''};
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            if ~isempty(app.data)
                output = app.data.show_fn(app.ShowafunctionEditField.Value);
                app.update_output(output);
            end
        end

        % Button pushed function: ComputeffButton
        function ComputeffButtonPushed(app, event)
            if ~isempty(app.data)
               output = app.data.bracket(app.fEditField.Value,app.fEditField_2.Value);
               app.update_output(output);
            end
        end

        % Button pushed function: ComputelogflogfButton
        function ComputelogflogfButtonPushed(app, event)
            if ~isempty(app.data)
               output = app.data.bracket(app.fEditField.Value,app.fEditField_2.Value,'log');
               app.update_output(output);
            end
        end

        % Button pushed function: ShowtherowRmatrixButton
        function ShowtherowRmatrixButtonPushed(app, event)
            if ~isempty(app.data)
                output = app.data.show_R('row',app.ShowonlytheimagesubspaceCheckBox.Value);
                app.update_output(output);
            end
        end

        % Button pushed function: ShowthecolumnRmatrixButton
        function ShowthecolumnRmatrixButtonPushed(app, event)
            if ~isempty(app.data)
                output = app.data.show_R('column',app.ShowonlytheimagesubspaceCheckBox.Value);
                app.update_output(output);
            end
        end

        % Button pushed function: ShowUButton
        function ShowUButtonPushed(app, event)
            if ~isempty(app.data)
                output = app.data.show_U();
                app.update_output(output);
            end
        end

        % Button pushed function: ComputeffButton_3
        function ComputeffButton_3Pushed(app, event)
            if ~isempty(app.data)
               output = app.data.bracket(app.fEditField.Value,app.fEditField_2.Value,'U_bracket');
               app.update_output(output);
            end            
        end

        % Button pushed function: ComputelogflogfButton_3
        function ComputelogflogfButton_3Pushed(app, event)
            if ~isempty(app.data)
               output = app.data.bracket(app.fEditField.Value,app.fEditField_2.Value,'log','U_bracket');
               app.update_output(output);
            end        
        end

        % Button pushed function: ComputeffButton_4
        function ComputeffButton_4Pushed(app, event)
            if ~isempty(app.data)
               output = app.data.bracket(app.fEditField.Value,app.fEditField_2.Value,'bracket_U_U');
               app.update_output(output);
            end
        end

        % Button pushed function: ComputelogflogfButton_4
        function ComputelogflogfButton_4Pushed(app, event)
           if ~isempty(app.data)
               output = app.data.bracket(app.fEditField.Value,app.fEditField_2.Value,'bracket_U_U','log');
               app.update_output(output);
           end
        end

        % Button pushed function: ShowU0Button
        function ShowU0ButtonPushed(app, event)
            if ~isempty(app.data)
                output = app.data.show_U0();
                app.update_output(output);
            end
        end

        % Button pushed function: ComputeffButton_2
        function ComputeffButton_2Pushed(app, event)
            if ~isempty(app.data)
                output = app.data.bracket(app.fEditField_3.Value,app.fEditField_4.Value,'wz');
                app.update_output(output)
            end      
        end

        % Button pushed function: ComputelogflogfButton_2
        function ComputelogflogfButton_2Pushed(app, event)
            if ~isempty(app.data)
                output = app.data.bracket(app.fEditField_3.Value,app.fEditField_4.Value,'wz','log');
                app.update_output(output);
            end      
        end

        % Button pushed function: ShowtherowRmatrixButton_2
        function ShowtherowRmatrixButton_2Pushed(app, event)
            if ~isempty(app.data)
                output = app.data.show_R('row','wz',app.ShowonlytheimagesubspaceCheckBox.Value);
                app.update_output(output);
            end
        end

        % Button pushed function: DifferenceofrowRmatricesButton
        function DifferenceofrowRmatricesButtonPushed(app, event)
            if ~isempty(app.data)
                output = app.data.show_R('row','diff',app.ShowonlytheimagesubspaceCheckBox.Value);
                app.update_output(output);
            end
        end

        % Button pushed function: PoissonCalculatorButton
        function PoissonCalculatorButtonPushed(app, event)
            if ~isempty(app.data)
                if ~app.data.only_quiver
                    app.PoissonCalculator = calculator(app);
                    app.PoissonCalculatorButton.Enable = 'off';
                else
                   app.update_output({'To run the calculator, beside the quiver one needs to construct at least some of the cluster variables.'});
                end
            else
               app.update_output({'To run the calculator, construct the cluster structure first.'}); 
            end
            
        end

        % Close request function: 
        % ClusterstructuresonDGLnandGLnUIFigure
        function ClusterstructuresonDGLnandGLnUIFigureCloseRequest(app, event)
            delete(app.PoissonCalculator);
            delete(app)
        end

        % Button pushed function: ShowinvUButton
        function ShowinvUButtonPushed(app, event)
            if ~isempty(app.data)
                output = app.data.show_invU();
                app.update_output(output);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClusterstructuresonDGLnandGLnUIFigure = uifigure('Visible', 'off');
            app.ClusterstructuresonDGLnandGLnUIFigure.Name = 'Cluster structures on D(GLn) and GLn';
            app.ClusterstructuresonDGLnandGLnUIFigure.CloseRequestFcn = createCallbackFcn(app, @ClusterstructuresonDGLnandGLnUIFigureCloseRequest, true);
            app.SettingsPanel = uipanel(app.ClusterstructuresonDGLnandGLnUIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.CreateButton = uibutton(app.SettingsPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Tooltip = {'Constructs a cluster structure on the Drinfeld double using the above BD-pair. Precisely, it constructs the functions and the quiver.'};
            app.CreateButton.Text = 'Create';
            app.nEditFieldLabel = uilabel(app.SettingsPanel);
            app.nEditFieldLabel.Text = 'n = ';
            app.nEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.nEditField.Value = 3;
            app.Label = uilabel(app.SettingsPanel);
            app.Label.Text = 'Γ₁ = ';
            app.EditField = uieditfield(app.SettingsPanel, 'text');
            app.RowBDdataLabel = uilabel(app.SettingsPanel);
            app.RowBDdataLabel.Text = 'Row BD-data:';
            app.Label_2 = uilabel(app.SettingsPanel);
            app.Label_2.Text = 'Γ₂ = ';
            app.EditField_2 = uieditfield(app.SettingsPanel, 'text');
            app.ColumnBDdataLabel = uilabel(app.SettingsPanel);
            app.ColumnBDdataLabel.Text = 'Column BD-data:';
            app.EditField_3Label = uilabel(app.SettingsPanel);
            app.EditField_3Label.Text = 'Γ₁ = ';
            app.EditField_3 = uieditfield(app.SettingsPanel, 'text');
            app.EditField_4Label = uilabel(app.SettingsPanel);
            app.EditField_4Label.Text = 'Γ₂ = ';
            app.EditField_4 = uieditfield(app.SettingsPanel, 'text');
            app.ConstructfunctionsCheckBox = uicheckbox(app.SettingsPanel);
            app.ConstructfunctionsCheckBox.Tooltip = {'Constructing φ-functions takes a noticeable amount of time. One may not want to check this box unless these functions will be used. Notice that the lower row of φ-functions is constructed anyway since it''s given by the extension of formulas for f-functions.'};
            app.ConstructfunctionsCheckBox.Text = 'Construct φ-functions';
            app.ConstructthebracketCheckBox = uicheckbox(app.SettingsPanel);
            app.ConstructthebracketCheckBox.Tooltip = {'Constructing the bracket takes some time since there''s a linear system that constructs R0. May want to uncheck if the bracket is not going to be used. '};
            app.ConstructthebracketCheckBox.Text = 'Construct the bracket';
            app.ConstructthebracketCheckBox.Value = true;
            app.ConstructcfunctionsCheckBox = uicheckbox(app.SettingsPanel);
            app.ConstructcfunctionsCheckBox.Tooltip = {'These are the functions that form isolated vertices on the quiver. If one doesn''t want to interact with them, it''s better to ucheck the box for faster performance.'};
            app.ConstructcfunctionsCheckBox.Text = 'Construct c-functions';
            app.CreateonlythequiverCheckBox = uicheckbox(app.SettingsPanel);
            app.CreateonlythequiverCheckBox.ValueChangedFcn = createCallbackFcn(app, @CreateonlythequiverCheckBoxValueChanged, true);
            app.CreateonlythequiverCheckBox.Tooltip = {'Again, constructing functions and mutating at them takes some time. If one wants only to see quiver mutations, check this box.'};
            app.CreateonlythequiverCheckBox.Text = 'Create only the quiver';
            app.ChoosethegroupButtonGroup = uibuttongroup(app.SettingsPanel);
            app.ChoosethegroupButtonGroup.Title = 'Choose the group';
            app.DGLnButton = uiradiobutton(app.ChoosethegroupButtonGroup);
            app.DGLnButton.Text = 'D(GLn)';
            app.DGLnButton.Value = true;
            app.GLnButton = uiradiobutton(app.ChoosethegroupButtonGroup);
            app.GLnButton.Enable = 'off';
            app.GLnButton.Text = 'GLn';
            app.ConstructthemapCheckBox = uicheckbox(app.SettingsPanel);
            app.ConstructthemapCheckBox.Tooltip = {'By default, the birational map is constructed from the deletion of the rightmost root in the first X-run.'};
            app.ConstructthemapCheckBox.Text = 'Construct the map 𝒰 ';
            app.PoissonCalculatorButton = uibutton(app.SettingsPanel, 'push');
            app.PoissonCalculatorButton.ButtonPushedFcn = createCallbackFcn(app, @PoissonCalculatorButtonPushed, true);
            app.PoissonCalculatorButton.Text = 'Poisson Calculator';
            app.OutputTextAreaLabel = uilabel(app.ClusterstructuresonDGLnandGLnUIFigure);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.ClusterstructuresonDGLnandGLnUIFigure);
            app.OutputTextArea.Editable = 'off';
            app.OutputpanelPanel = uipanel(app.ClusterstructuresonDGLnandGLnUIFigure);
            app.OutputpanelPanel.Title = 'Output panel';
            app.ShowthequiverButton = uibutton(app.OutputpanelPanel, 'push');
            app.ShowthequiverButton.ButtonPushedFcn = createCallbackFcn(app, @ShowthequiverButtonPushed, true);
            app.ShowthequiverButton.Tooltip = {'Shows the quiver in the current seed.'};
            app.ShowthequiverButton.Text = 'Show the quiver';
            app.ShowallLfunctionsButton = uibutton(app.OutputpanelPanel, 'push');
            app.ShowallLfunctionsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowallLfunctionsButtonPushed, true);
            app.ShowallLfunctionsButton.Text = 'Show all L-functions';
            app.ShowafunctionEditFieldLabel = uilabel(app.OutputpanelPanel);
            app.ShowafunctionEditFieldLabel.Text = 'Show a function:';
            app.ShowafunctionEditField = uieditfield(app.OutputpanelPanel, 'text');
            app.ShowafunctionEditField.Tooltip = {'E.g., enter g(1,1) and then press Show button.'};
            app.ShowButton = uibutton(app.OutputpanelPanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.CleartheoutputButton = uibutton(app.OutputpanelPanel, 'push');
            app.CleartheoutputButton.ButtonPushedFcn = createCallbackFcn(app, @CleartheoutputButtonPushed, true);
            app.CleartheoutputButton.Text = 'Clear the output';
            app.ShowUButton = uibutton(app.OutputpanelPanel, 'push');
            app.ShowUButton.ButtonPushedFcn = createCallbackFcn(app, @ShowUButtonPushed, true);
            app.ShowUButton.Tooltip = {'Displays the matrix U if it has been constructed.'};
            app.ShowUButton.Text = 'Show U';
            app.AcceptedtypesforDGLnghfpcuu0aLabel = uilabel(app.OutputpanelPanel);
            app.AcceptedtypesforDGLnghfpcuu0aLabel.Text = 'Accepted types for D(GLn): g, h, f, p, c, u, u0, a.';
            app.ShowU0Button = uibutton(app.OutputpanelPanel, 'push');
            app.ShowU0Button.ButtonPushedFcn = createCallbackFcn(app, @ShowU0ButtonPushed, true);
            app.ShowU0Button.Text = 'Show U0';
            app.ShowinvUButton = uibutton(app.OutputpanelPanel, 'push');
            app.ShowinvUButton.ButtonPushedFcn = createCallbackFcn(app, @ShowinvUButtonPushed, true);
            app.ShowinvUButton.Tooltip = {'Displays the matrix U if it has been constructed.'};
            app.ShowinvUButton.Text = 'Show inv(U)';
            app.TabGroup = uitabgroup(app.ClusterstructuresonDGLnandGLnUIFigure);
            app.MutationsinXYTab = uitab(app.TabGroup);
            app.MutationsinXYTab.Title = 'Mutations in (X,Y)';
            app.MutateButton = uibutton(app.MutationsinXYTab, 'push');
            app.MutateButton.ButtonPushedFcn = createCallbackFcn(app, @MutateButtonPushed, true);
            app.MutateButton.Tooltip = {'Mutates along the entered sequence.'};
            app.MutateButton.Text = 'Mutate';
            app.MutationsequenceEditFieldLabel = uilabel(app.MutationsinXYTab);
            app.MutationsequenceEditFieldLabel.Text = 'Mutation sequence:';
            app.MutationsequenceEditField = uieditfield(app.MutationsinXYTab, 'text');
            app.MutationsequenceEditField.Tooltip = {'For example, on the double: h(1,2), h(2,3), p(3,1), f(1,1), g(2,3)'; 'Enter φ-functions as p. E.g., instead of φ(1,1) enter p(1,1).'; 'On GLn, enter either f(1,1), f(1,2), etc., or simply drop the f: (1,1), (1,2), (1,3).'};
            app.BracketinXYTab = uitab(app.TabGroup);
            app.BracketinXYTab.Title = 'Bracket in (X,Y)';
            app.fLabel = uilabel(app.BracketinXYTab);
            app.fLabel.Text = 'f₁ = ';
            app.fEditField = uieditfield(app.BracketinXYTab, 'text');
            app.fEditField.Tooltip = {'E.g., enter g(3,1) for the double. In case of Gln, enter f(3,1) or just (3,1).'};
            app.fLabel_2 = uilabel(app.BracketinXYTab);
            app.fLabel_2.Text = 'f₂ = ';
            app.fEditField_2 = uieditfield(app.BracketinXYTab, 'text');
            app.ComputeffButton = uibutton(app.BracketinXYTab, 'push');
            app.ComputeffButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeffButtonPushed, true);
            app.ComputeffButton.Text = 'Compute {f₁,f₂}';
            app.ComputelogflogfButton = uibutton(app.BracketinXYTab, 'push');
            app.ComputelogflogfButton.ButtonPushedFcn = createCallbackFcn(app, @ComputelogflogfButtonPushed, true);
            app.ComputelogflogfButton.Text = 'Compute {log(f₁),log(f₂)}';
            app.ComputeffButton_3 = uibutton(app.BracketinXYTab, 'push');
            app.ComputeffButton_3.ButtonPushedFcn = createCallbackFcn(app, @ComputeffButton_3Pushed, true);
            app.ComputeffButton_3.Text = 'Compute 𝒰{f₁,f₂}';
            app.ComputelogflogfButton_3 = uibutton(app.BracketinXYTab, 'push');
            app.ComputelogflogfButton_3.ButtonPushedFcn = createCallbackFcn(app, @ComputelogflogfButton_3Pushed, true);
            app.ComputelogflogfButton_3.Text = 'Compute 𝒰{log(f₁),log(f₂)}';
            app.ComputeffButton_4 = uibutton(app.BracketinXYTab, 'push');
            app.ComputeffButton_4.ButtonPushedFcn = createCallbackFcn(app, @ComputeffButton_4Pushed, true);
            app.ComputeffButton_4.Text = 'Compute {𝒰(f₁),𝒰(f₂)}';
            app.ComputelogflogfButton_4 = uibutton(app.BracketinXYTab, 'push');
            app.ComputelogflogfButton_4.ButtonPushedFcn = createCallbackFcn(app, @ComputelogflogfButton_4Pushed, true);
            app.ComputelogflogfButton_4.Text = 'Compute {𝒰(log(f₁)),𝒰(log(f₂))}';
            app.AcceptedtypesforDGLnghfpcxyLabel = uilabel(app.BracketinXYTab);
            app.AcceptedtypesforDGLnghfpcxyLabel.Text = 'Accepted types for D(GLn): g, h, f, p, c, x, y.';
            app.BracketinWZTab = uitab(app.TabGroup);
            app.BracketinWZTab.Title = 'Bracket in (W,Z)';
            app.fLabel_3 = uilabel(app.BracketinWZTab);
            app.fLabel_3.Text = 'f₁ = ';
            app.ComputeffButton_2 = uibutton(app.BracketinWZTab, 'push');
            app.ComputeffButton_2.ButtonPushedFcn = createCallbackFcn(app, @ComputeffButton_2Pushed, true);
            app.ComputeffButton_2.Text = 'Compute {f₁,f₂}';
            app.fLabel_5 = uilabel(app.BracketinWZTab);
            app.fLabel_5.Text = 'f₂ = ';
            app.ComputelogflogfButton_2 = uibutton(app.BracketinWZTab, 'push');
            app.ComputelogflogfButton_2.ButtonPushedFcn = createCallbackFcn(app, @ComputelogflogfButton_2Pushed, true);
            app.ComputelogflogfButton_2.Text = 'Compute {log(f₁),log(f₂)}';
            app.NOTEifUisconstructedonecancomputeUWLabel = uilabel(app.BracketinWZTab);
            app.NOTEifUisconstructedonecancomputeUWLabel.Text = 'NOTE: if U is constructed, one can compute {U,W}. ';
            app.fEditField_4 = uieditfield(app.BracketinWZTab, 'text');
            app.fEditField_3 = uieditfield(app.BracketinWZTab, 'text');
            app.fEditField_3.Tooltip = {'E.g., enter g(3,1) for the double. In case of Gln, enter f(3,1) or just (3,1).'};
            app.Forinstancetypeu21w11Label = uilabel(app.BracketinWZTab);
            app.Forinstancetypeu21w11Label.Text = 'For instance, type {u(2,1), w(1,1)}.';
            app.AcceptedtypesforDGLnghfpcuu0wzaLabel = uilabel(app.BracketinWZTab);
            app.AcceptedtypesforDGLnghfpcuu0wzaLabel.Text = 'Accepted types for D(GLn): g, h, f, p, c, u, u0, w, z, a.';
            app.RmatrixTab = uitab(app.TabGroup);
            app.RmatrixTab.Title = 'R-matrix';
            app.ShowtherowRmatrixButton = uibutton(app.RmatrixTab, 'push');
            app.ShowtherowRmatrixButton.ButtonPushedFcn = createCallbackFcn(app, @ShowtherowRmatrixButtonPushed, true);
            app.ShowtherowRmatrixButton.Text = 'Show the row R-matrix';
            app.ShowthecolumnRmatrixButton = uibutton(app.RmatrixTab, 'push');
            app.ShowthecolumnRmatrixButton.ButtonPushedFcn = createCallbackFcn(app, @ShowthecolumnRmatrixButtonPushed, true);
            app.ShowthecolumnRmatrixButton.Text = 'Show the column R-matrix';
            app.OntheXYvarietyLabel = uilabel(app.RmatrixTab);
            app.OntheXYvarietyLabel.Text = 'On the (X,Y)-variety:';
            app.OntheWZvarietyLabel = uilabel(app.RmatrixTab);
            app.OntheWZvarietyLabel.Text = 'On the (W,Z)-variety:';
            app.ShowtherowRmatrixButton_2 = uibutton(app.RmatrixTab, 'push');
            app.ShowtherowRmatrixButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowtherowRmatrixButton_2Pushed, true);
            app.ShowtherowRmatrixButton_2.Text = 'Show the row R-matrix';
            app.DifferenceofrowRmatricesButton = uibutton(app.RmatrixTab, 'push');
            app.DifferenceofrowRmatricesButton.ButtonPushedFcn = createCallbackFcn(app, @DifferenceofrowRmatricesButtonPushed, true);
            app.DifferenceofrowRmatricesButton.Text = 'Difference of row R-matrices';
            app.ShowonlytheimagesubspaceCheckBox = uicheckbox(app.RmatrixTab);
            app.ShowonlytheimagesubspaceCheckBox.Text = 'Show only the image subspace';
            app.ConventiononTab = uitab(app.TabGroup);
            app.ConventiononTab.Title = 'Convention on { , }';
            app.Image = uiimage(app.ConventiononTab);
            app.Image.ImageSource = 'formula.png';
            % Show the figure after all components are created
            app.ClusterstructuresonDGLnandGLnUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = launcher

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClusterstructuresonDGLnandGLnUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClusterstructuresonDGLnandGLnUIFigure)
        end
    end
end